classdef congestionControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        i14                        matlab.ui.control.Image
        i17                        matlab.ui.control.Image
        i16                        matlab.ui.control.Image
        i81                        matlab.ui.control.Image
        i15                        matlab.ui.control.Image
        i35                        matlab.ui.control.Image
        i19                        matlab.ui.control.Image
        i20                        matlab.ui.control.Image
        i34                        matlab.ui.control.Image
        i11                        matlab.ui.control.Image
        i12                        matlab.ui.control.Image
        i3                         matlab.ui.control.Image
        i43                        matlab.ui.control.Image
        i53                        matlab.ui.control.Image
        i63                        matlab.ui.control.Image
        i28                        matlab.ui.control.Image
        i23                        matlab.ui.control.Image
        i93                        matlab.ui.control.Image
        i73                        matlab.ui.control.Image
        i10                        matlab.ui.control.Image
        i5                         matlab.ui.control.Image
        i26                        matlab.ui.control.Image
        i29                        matlab.ui.control.Image
        i7                         matlab.ui.control.Image
        i1                         matlab.ui.control.Image
        i2                         matlab.ui.control.Image
        i91                        matlab.ui.control.Image
        i42                        matlab.ui.control.Image
        i6                         matlab.ui.control.Image
        i96                        matlab.ui.control.Image
        i97                        matlab.ui.control.Image
        i79                        matlab.ui.control.Image
        i30                        matlab.ui.control.Image
        i21                        matlab.ui.control.Image
        i22                        matlab.ui.control.Image
        i92                        matlab.ui.control.Image
        i41                        matlab.ui.control.Image
        i69                        matlab.ui.control.Image
        i46                        matlab.ui.control.Image
        i38                        matlab.ui.control.Image
        i18                        matlab.ui.control.Image
        i13                        matlab.ui.control.Image
        i33                        matlab.ui.control.Image
        i72                        matlab.ui.control.Image
        i24                        matlab.ui.control.Image
        i77                        matlab.ui.control.Image
        i65                        matlab.ui.control.Image
        i47                        matlab.ui.control.Image
        i25                        matlab.ui.control.Image
        i49                        matlab.ui.control.Image
        i59                        matlab.ui.control.Image
        i94                        matlab.ui.control.Image
        i64                        matlab.ui.control.Image
        i4                         matlab.ui.control.Image
        i95                        matlab.ui.control.Image
        i61                        matlab.ui.control.Image
        i71                        matlab.ui.control.Image
        i70                        matlab.ui.control.Image
        i60                        matlab.ui.control.Image
        i50                        matlab.ui.control.Image
        i9                         matlab.ui.control.Image
        i80                        matlab.ui.control.Image
        i51                        matlab.ui.control.Image
        i54                        matlab.ui.control.Image
        i76                        matlab.ui.control.Image
        i56                        matlab.ui.control.Image
        i100                       matlab.ui.control.Image
        i44                        matlab.ui.control.Image
        i36                        matlab.ui.control.Image
        i37                        matlab.ui.control.Image
        i39                        matlab.ui.control.Image
        i40                        matlab.ui.control.Image
        i48                        matlab.ui.control.Image
        i58                        matlab.ui.control.Image
        i68                        matlab.ui.control.Image
        i78                        matlab.ui.control.Image
        i86                        matlab.ui.control.Image
        i85                        matlab.ui.control.Image
        i87                        matlab.ui.control.Image
        i82                        matlab.ui.control.Image
        i98                        matlab.ui.control.Image
        i88                        matlab.ui.control.Image
        i99                        matlab.ui.control.Image
        i89                        matlab.ui.control.Image
        i90                        matlab.ui.control.Image
        i31                        matlab.ui.control.Image
        i32                        matlab.ui.control.Image
        i52                        matlab.ui.control.Image
        i62                        matlab.ui.control.Image
        i8                         matlab.ui.control.Image
        i74                        matlab.ui.control.Image
        i84                        matlab.ui.control.Image
        i83                        matlab.ui.control.Image
        StartaddtrafficButton      matlab.ui.control.StateButton
        SimulatestepsintimeButton  matlab.ui.control.Button
        i9_2                       matlab.ui.control.Image
        i9_3                       matlab.ui.control.Image
        i9_4                       matlab.ui.control.Image
        i9_5                       matlab.ui.control.Image
        i9_6                       matlab.ui.control.Image
        i9_7                       matlab.ui.control.Image
        i9_8                       matlab.ui.control.Image
        i9_9                       matlab.ui.control.Image
        i9_10                      matlab.ui.control.Image
        i9_11                      matlab.ui.control.Image
        i24_2                      matlab.ui.control.Image
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        housesCount = 19 ;      %number of houses on grid
        carsCount  = 40;        %2 per house, 3 from the mayors house
        road = 48;      %if 2 cars were on each side of the road.
        BaseStation=1;  %base station
        cellTowers=3 ; %each should have bandwith and other specs
        trafficlevel=0;  %value between 0 and 10
        rowLoc = 0;
        colLoc = 0;
        directions = 0; %used to save if a road is horizontal or vertical
        imageNames= 0;
        metaData = 0;
        testName =0' %for setting image
        roadIndex = 0; %for indexing the meta data and finding roads
        testthing = 0; %test for traffic layout
        newIM =0;
        element = 0;
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %TASK:get the number of homes(used to calculate traffick)
            %and the location and number of roads.
            %set intiaz values
            app.housesCount = 0;
            app.road = 0;
            app.roadIndex = 0;
            app.directions = [0,0];
            
            
            %get all graphic objects
            app.metaData= findall(app.GridLayout);
            
            %step through each object
            for i=5:80
                
                %test first item
                app.element = app.metaData(i,1);
                
                type = class(app.element)
                
                if ~strcmp(type,'matlab.ui.control.UIAxes') && ~strcmp(type,'matlab.graphics.shape.internal.AnnotationPane')
                %record the name
                namePlease = app.element.ImageSource
                
                %count the red houses
                if namePlease == "redHouse.PNG"
                    app.housesCount = app.housesCount+1;
                    
                elseif namePlease =="bighorizontalroad.PNG" || namePlease == "wideVerticalroad.PNG"
                    app.road = app.road+1;
                    app.roadIndex(end+1) = i
                    %get the location on the grid
                    app.rowLoc(end+1) = app.element.Layout.Row;
                    app.colLoc(end+1) = app.element.Layout.Column;
                    
                    if namePlease =="bighorizontalroad.PNG"
                        app.directions(end+1) = 0; %indicates horizontal road
                    else
                        app.directions(end+1) = 1;  %indicates vertical road
                    end
                    
                end
                end
                
                %testing/debugging purposes
                house = app.housesCount;
                road = app.road;
                acrossRoad = app.rowLoc;
                verticalRoad = app.colLoc;
                dir = app.directions;
                rindex = app.roadIndex
                
            end
            
           
        end

        % Value changed function: StartaddtrafficButton
        function StartaddtrafficButtonValueChanged(app, event)
            value = app.StartaddtrafficButton.Value;
            %when pressed the simulation should begin
            tick = 1;
            %first select 15 random values out of the 39 locations
            %use randomperm function
            carLimit = 24
            
            %to really do this the right way, I am gogint to utilize the
            %row and column loc variables from,although I need to go back
            %and also save the corresponding index of the metaData, in
            %order to access the object image and change src image (i
            %think)
            app.road;
            rows = app.rowLoc;
            cols = app.colLoc;
            rindex = app.roadIndex;

            %ntiialize loop variables
            count = 0;
            loops = 0;
            metIndex = 0;
            
            %39 is the length of the road data
            for i=1:25
                
                %relate meta data to the road index
                metIndex = app.roadIndex(i)
                if metIndex >85
                    metIndex  =  85;
                end
                
                %get the object
                 hope = app.metaData(metIndex+6,1);
                 type = class(hope);
                 if ~strcmp(type,'matlab.ui.control.UIAxes') && ~strcmp(type,'matlab.graphics.shape.internal.AnnotationPane')
                app.testthing  = hope.ImageSource
                
                %the odds should be based off a slider value now leave it
                %as is
                odds = 5;
                chance = randi(odds);
                
                %now assign the new image accordingly
                if app.testthing == "bighorizontalroad.PNG" && (count <carLimit) && (chance == 1)
                    %replace image correctly with horizontal
                    %decide between double car or single car on side of
                    %road
                    nchance = randi(4);
                    
                    if nchance == 3
                        set(hope, 'ImageSource', 'horRight.png')
                        count = count +1;
                    elseif nchance == 1 | nchance == 2
                        set(hope, 'ImageSource', 'horLeft.png')
                        count = count +1;
                    else
                        set(hope, 'ImageSource', 'horBoth.png')
                        count = count +2;
                    end
                    
                    
                elseif  app.testthing == "wideVerticalroad.PNG" && (count <carLimit) && (chance == 1)
                    %set horizontal image
                    nchance = randi(4);
                    
                    if nchance == 3
                        set(hope, 'ImageSource', 'vertRight.png')
                        count = count +1;
                    elseif nchance ==1 | nchance == 2
                        set(hope, 'ImageSource', 'vertLeft.png')
                        count = count +1;
                    else
                        set(hope, 'ImageSource', 'vertBoth.png')
                        count = count +2;
                        
                    end
                    
                end
                loops = loops +1;
                 
            end
            
            %%I just made a nice breathrough that probably shaved off much more than
            %%the time I took off, though I need to keep a good temp and breathe
            %%sort through the meta data, determine the row and column of
            %%images cntaining cars, and then plot accordingly 
            Tower1 = 0;
            Tower2 = 0;
            Tower3 = 0;
            Tower4 = 0;
            
            for i=4:90
               this  = app.metaData(i,1);
                type = class(this);
                 if ~strcmp(type,'matlab.ui.control.UIAxes') && ~strcmp(type,'matlab.graphics.shape.internal.AnnotationPane')
               result = this.ImageSource;
               r = this.Layout.Row;
               c = this.Layout.Column;


               if strcmp(result,'horLeft.png') || strcmp(result,'horRight.png') || strcmp(result,'horBoth.png') || strcmp(result,'vertLeft.png') || strcmp(result,'vertRight.png') || strcmp(result,'vertBoth.png')
               
                   if r <5 && c < 5 
                       Tower1 = Tower1 + 1;
                       
                   elseif r >5  && c < 5
                       Tower3 = Tower3 + 1;
                       
                   elseif r<5 && c >5
                       
                       Tower2 = Tower2 + 1;
                       
                   elseif r>6 && c>6
                       
                       Tower4 = Tower4 + 1 ;
                   end 
               end
               
                 end
            end
            end
            
            
    x = .5
  
%   Now play the data on  bar graph, x data is the tick, and y data is
%   percent of total, should change with each lick ASAP
  sumTraf = Tower1 + Tower2 + Tower3 + Tower4;
  
  data1 = (Tower1/sumTraf) *100
  bar(app.UIAxes, x,data1)
  hold on
  
  data2 = (Tower2/sumTraf) *100
  bar(app.UIAxes_2,x, data2)
  hold on
  
  data3 = (Tower3/sumTraf) *100
  bar(app.UIAxes_3,x, data3)
  hold on
  
  data4 = (Tower4/sumTraf) *100
  bar(app.UIAxes_4, x, data4)
  pause(.5)
  hold on
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.i14 = uiimage(app.GridLayout);
            app.i14.ImageSource = 'bighorizontalroad.PNG';
            app.i17 = uiimage(app.GridLayout);
            app.i17.ImageSource = 'bighorizontalroad.PNG';
            app.i16 = uiimage(app.GridLayout);
            app.i16.ImageSource = 'bighorizontalroad.PNG';
            app.i81 = uiimage(app.GridLayout);
            app.i81.ImageSource = 'bighorizontalroad.PNG';
            app.i15 = uiimage(app.GridLayout);
            app.i15.ImageSource = 'bighorizontalroad.PNG';
            app.i35 = uiimage(app.GridLayout);
            app.i35.ImageSource = 'bighorizontalroad.PNG';
            app.i19 = uiimage(app.GridLayout);
            app.i19.ImageSource = 'bighorizontalroad.PNG';
            app.i20 = uiimage(app.GridLayout);
            app.i20.ImageSource = 'bighorizontalroad.PNG';
            app.i34 = uiimage(app.GridLayout);
            app.i34.ImageSource = 'bighorizontalroad.PNG';
            app.i11 = uiimage(app.GridLayout);
            app.i11.ImageSource = 'bighorizontalroad.PNG';
            app.i12 = uiimage(app.GridLayout);
            app.i12.ImageSource = 'bighorizontalroad.PNG';
            app.i3 = uiimage(app.GridLayout);
            app.i3.ImageSource = 'wideVerticalroad.PNG';
            app.i43 = uiimage(app.GridLayout);
            app.i43.ImageSource = 'wideVerticalroad.PNG';
            app.i53 = uiimage(app.GridLayout);
            app.i53.ImageSource = 'wideVerticalroad.PNG';
            app.i63 = uiimage(app.GridLayout);
            app.i63.ImageSource = 'wideVerticalroad.PNG';
            app.i28 = uiimage(app.GridLayout);
            app.i28.ImageSource = 'wideVerticalroad.PNG';
            app.i23 = uiimage(app.GridLayout);
            app.i23.ImageSource = 'wideVerticalroad.PNG';
            app.i93 = uiimage(app.GridLayout);
            app.i93.ImageSource = 'wideVerticalroad.PNG';
            app.i73 = uiimage(app.GridLayout);
            app.i73.ImageSource = 'wideVerticalroad.PNG';
            app.i10 = uiimage(app.GridLayout);
            app.i10.ImageSource = 'redHouse.PNG';
            app.i5 = uiimage(app.GridLayout);
            app.i5.ImageSource = 'redHouse.PNG';
            app.i26 = uiimage(app.GridLayout);
            app.i26.ImageSource = 'redHouse.PNG';
            app.i29 = uiimage(app.GridLayout);
            app.i29.ImageSource = 'redHouse.PNG';
            app.i7 = uiimage(app.GridLayout);
            app.i7.ImageSource = 'redHouse.PNG';
            app.i1 = uiimage(app.GridLayout);
            app.i1.Tag = '1.1';
            app.i1.ImageSource = 'redHouse.PNG';
            app.i2 = uiimage(app.GridLayout);
            app.i2.ImageSource = 'redHouse.PNG';
            app.i91 = uiimage(app.GridLayout);
            app.i91.ImageSource = 'redHouse.PNG';
            app.i42 = uiimage(app.GridLayout);
            app.i42.ImageSource = 'redHouse.PNG';
            app.i6 = uiimage(app.GridLayout);
            app.i6.ImageSource = 'redHouse.PNG';
            app.i96 = uiimage(app.GridLayout);
            app.i96.ImageSource = 'redHouse.PNG';
            app.i97 = uiimage(app.GridLayout);
            app.i97.ImageSource = 'redHouse.PNG';
            app.i79 = uiimage(app.GridLayout);
            app.i79.ImageSource = 'redHouse.PNG';
            app.i30 = uiimage(app.GridLayout);
            app.i30.ImageSource = 'redHouse.PNG';
            app.i21 = uiimage(app.GridLayout);
            app.i21.ImageSource = 'redHouse.PNG';
            app.i22 = uiimage(app.GridLayout);
            app.i22.ImageSource = 'redHouse.PNG';
            app.i92 = uiimage(app.GridLayout);
            app.i92.ImageSource = 'redHouse.PNG';
            app.i41 = uiimage(app.GridLayout);
            app.i41.ImageSource = 'park.PNG';
            app.i69 = uiimage(app.GridLayout);
            app.i69.ImageSource = 'park.PNG';
            app.i46 = uiimage(app.GridLayout);
            app.i46.ImageSource = 'polce.PNG';
            app.i38 = uiimage(app.GridLayout);
            app.i38.ImageSource = 'astoplight.PNG';
            app.i18 = uiimage(app.GridLayout);
            app.i18.ImageSource = 'astoplight.PNG';
            app.i13 = uiimage(app.GridLayout);
            app.i13.ImageSource = 'astoplight.PNG';
            app.i33 = uiimage(app.GridLayout);
            app.i33.ImageSource = 'astoplight.PNG';
            app.i72 = uiimage(app.GridLayout);
            app.i72.ImageSource = 'blueTower.PNG';
            app.i24 = uiimage(app.GridLayout);
            app.i24.ImageSource = 'blueTower.PNG';
            app.i77 = uiimage(app.GridLayout);
            app.i77.ImageSource = 'blueTower.PNG';
            app.i65 = uiimage(app.GridLayout);
            app.i65.ImageSource = 'baseStation.PNG';
            app.i47 = uiimage(app.GridLayout);
            app.i47.ImageSource = 'bistro.PNG';
            app.i25 = uiimage(app.GridLayout);
            app.i25.ImageSource = 'bistro.PNG';
            app.i49 = uiimage(app.GridLayout);
            app.i49.ImageSource = 'convienceStore.PNG';
            app.i59 = uiimage(app.GridLayout);
            app.i59.ImageSource = 'gasstation.PNG';
            app.i94 = uiimage(app.GridLayout);
            app.i94.ImageSource = 'gasstation.PNG';
            app.i64 = uiimage(app.GridLayout);
            app.i64.ImageSource = 'bank.PNG';
            app.i4 = uiimage(app.GridLayout);
            app.i4.ImageSource = 'pond.PNG';
            app.i95 = uiimage(app.GridLayout);
            app.i95.ImageSource = 'pond.PNG';
            app.i61 = uiimage(app.GridLayout);
            app.i61.ImageSource = 'tree.PNG';
            app.i71 = uiimage(app.GridLayout);
            app.i71.ImageSource = 'tree.PNG';
            app.i70 = uiimage(app.GridLayout);
            app.i70.ImageSource = 'tree.PNG';
            app.i60 = uiimage(app.GridLayout);
            app.i60.ImageSource = 'tree.PNG';
            app.i50 = uiimage(app.GridLayout);
            app.i50.ImageSource = 'tree.PNG';
            app.i9 = uiimage(app.GridLayout);
            app.i9.ImageSource = 'tree.PNG';
            app.i80 = uiimage(app.GridLayout);
            app.i80.ImageSource = 'tree.PNG';
            app.i51 = uiimage(app.GridLayout);
            app.i51.ImageSource = 'tree.PNG';
            app.i54 = uiimage(app.GridLayout);
            app.i54.ImageSource = 'outOffice.PNG';
            app.i76 = uiimage(app.GridLayout);
            app.i76.ImageSource = 'outOffice.PNG';
            app.i56 = uiimage(app.GridLayout);
            app.i56.ImageSource = 'Stadium.PNG';
            app.i100 = uiimage(app.GridLayout);
            app.i100.ImageSource = 'golfCourse.PNG';
            app.i44 = uiimage(app.GridLayout);
            app.i44.ImageSource = 'gym.PNG';
            app.i36 = uiimage(app.GridLayout);
            app.i36.ImageSource = 'bighorizontalroad.PNG';
            app.i37 = uiimage(app.GridLayout);
            app.i37.ImageSource = 'bighorizontalroad.PNG';
            app.i39 = uiimage(app.GridLayout);
            app.i39.ImageSource = 'bighorizontalroad.PNG';
            app.i40 = uiimage(app.GridLayout);
            app.i40.ImageSource = 'bighorizontalroad.PNG';
            app.i48 = uiimage(app.GridLayout);
            app.i48.ImageSource = 'wideVerticalroad.PNG';
            app.i58 = uiimage(app.GridLayout);
            app.i58.ImageSource = 'wideVerticalroad.PNG';
            app.i68 = uiimage(app.GridLayout);
            app.i68.ImageSource = 'wideVerticalroad.PNG';
            app.i78 = uiimage(app.GridLayout);
            app.i78.ImageSource = 'wideVerticalroad.PNG';
            app.i86 = uiimage(app.GridLayout);
            app.i86.ImageSource = 'bighorizontalroad.PNG';
            app.i85 = uiimage(app.GridLayout);
            app.i85.ImageSource = 'bighorizontalroad.PNG';
            app.i87 = uiimage(app.GridLayout);
            app.i87.ImageSource = 'bighorizontalroad.PNG';
            app.i82 = uiimage(app.GridLayout);
            app.i82.ImageSource = 'bighorizontalroad.PNG';
            app.i98 = uiimage(app.GridLayout);
            app.i98.ImageSource = 'wideVerticalroad.PNG';
            app.i88 = uiimage(app.GridLayout);
            app.i88.ImageSource = 'astoplight.PNG';
            app.i99 = uiimage(app.GridLayout);
            app.i99.ImageSource = 'golfCourse.PNG';
            app.i89 = uiimage(app.GridLayout);
            app.i89.ImageSource = 'bighorizontalroad.PNG';
            app.i90 = uiimage(app.GridLayout);
            app.i90.ImageSource = 'bighorizontalroad.PNG';
            app.i31 = uiimage(app.GridLayout);
            app.i31.ImageSource = 'bighorizontalroad.PNG';
            app.i32 = uiimage(app.GridLayout);
            app.i32.ImageSource = 'bighorizontalroad.PNG';
            app.i52 = uiimage(app.GridLayout);
            app.i52.ImageSource = 'redHouse.PNG';
            app.i62 = uiimage(app.GridLayout);
            app.i62.ImageSource = 'redHouse.PNG';
            app.i8 = uiimage(app.GridLayout);
            app.i8.ImageSource = 'wideVerticalroad.PNG';
            app.i74 = uiimage(app.GridLayout);
            app.i74.ImageSource = 'school.PNG';
            app.i84 = uiimage(app.GridLayout);
            app.i84.ImageSource = 'bighorizontalroad.PNG';
            app.i83 = uiimage(app.GridLayout);
            app.i83.ImageSource = 'astoplight.PNG';
            app.StartaddtrafficButton = uibutton(app.GridLayout, 'state');
            app.StartaddtrafficButton.ValueChangedFcn = createCallbackFcn(app, @StartaddtrafficButtonValueChanged, true);
            app.StartaddtrafficButton.Text = 'Start / add traffic';
            app.SimulatestepsintimeButton = uibutton(app.GridLayout, 'push');
            app.SimulatestepsintimeButton.Text = 'Simulate steps in time ';
            app.i9_2 = uiimage(app.GridLayout);
            app.i9_2.ImageSource = 'tree.PNG';
            app.i9_3 = uiimage(app.GridLayout);
            app.i9_3.ImageSource = 'tree.PNG';
            app.i9_4 = uiimage(app.GridLayout);
            app.i9_4.ImageSource = 'tree.PNG';
            app.i9_5 = uiimage(app.GridLayout);
            app.i9_5.ImageSource = 'tree.PNG';
            app.i9_6 = uiimage(app.GridLayout);
            app.i9_6.ImageSource = 'tree.PNG';
            app.i9_7 = uiimage(app.GridLayout);
            app.i9_7.ImageSource = 'tree.PNG';
            app.i9_8 = uiimage(app.GridLayout);
            app.i9_8.ImageSource = 'tree.PNG';
            app.i9_9 = uiimage(app.GridLayout);
            app.i9_9.ImageSource = 'tree.PNG';
            app.i9_10 = uiimage(app.GridLayout);
            app.i9_10.ImageSource = 'tree.PNG';
            app.i9_11 = uiimage(app.GridLayout);
            app.i9_11.ImageSource = 'tree.PNG';
            app.i24_2 = uiimage(app.GridLayout);
            app.i24_2.ImageSource = 'blueTower.PNG';
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Tower4')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XLim = [0 1];
            app.UIAxes_4.YLim = [0 100];
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Tower3')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YLim = [0 100];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Tower2')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [0 100];
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Tower1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = congestionControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end